classdef appBMConfAlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParmetrosdelosalgoritmosUIFigure  matlab.ui.Figure
        EDAPanel                     matlab.ui.container.Panel
        MaxItEditFieldLabel          matlab.ui.control.Label
        EDAMaxItEditField            matlab.ui.control.NumericEditField
        MaxPopEditFieldLabel         matlab.ui.control.Label
        EDAMaxPopEditField           matlab.ui.control.NumericEditField
        numParentsEditFieldLabel     matlab.ui.control.Label
        EDAnumParentsEditField       matlab.ui.control.NumericEditField
        SAPanel                      matlab.ui.container.Panel
        MaxItEditField_2Label        matlab.ui.control.Label
        SAMaxItEditField             matlab.ui.control.NumericEditField
        T0EditFieldLabel             matlab.ui.control.Label
        SAT0EditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel          matlab.ui.control.Label
        SAalphaEditField             matlab.ui.control.NumericEditField
        MaxIt2EditFieldLabel         matlab.ui.control.Label
        SAMaxIt2EditField            matlab.ui.control.NumericEditField
        VNSPanel                     matlab.ui.container.Panel
        MaxItEditField_3Label        matlab.ui.control.Label
        VNSMaxItEditField            matlab.ui.control.NumericEditField
        MaxKEditFieldLabel           matlab.ui.control.Label
        VNSMaxKEditField             matlab.ui.control.NumericEditField
        GRASPPanel                   matlab.ui.container.Panel
        MaxItEditField_4Label        matlab.ui.control.Label
        GRASPMaxItEditField          matlab.ui.control.NumericEditField
        MaxIt2EditField_2Label       matlab.ui.control.Label
        GRASPMaxIt2EditField         matlab.ui.control.NumericEditField
        CerrarButton                 matlab.ui.control.Button
        InicializarpordefectoButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        config % Description
        config_changed % Description
    end
    
    methods (Access = private)
        
        function Inicializar(app, filename)
            
            app.config_changed = false;
            root_path = dir('main.m');
            % aquí tengo los datos de la configuracion cargados del archivo.
%             app.config = SelectConfigAlgNGUI(strcat(root_path.folder,'\'),'ConfigAlg.mat');
            app.config = SelectConfigAlgNGUI(strcat(root_path.folder,'\'),filename);
             
            app.EDAMaxItEditField.Value = app.config.EDA.MaxIt;
            app.EDAMaxPopEditField.Value = app.config.EDA.MaxPop;
            app.EDAnumParentsEditField.Value = app.config.EDA.numParents;
            
            app.SAMaxItEditField.Value = app.config.SA.MaxIt;
            app.SAMaxIt2EditField.Value = app.config.SA.MaxIt2;
            app.SAT0EditField.Value = app.config.SA.T0;
            app.SAalphaEditField.Value = app.config.SA.alpha;
            
            app.VNSMaxItEditField.Value = app.config.VNS.MaxIt;
            app.VNSMaxKEditField.Value = app.config.VNS.maxK;
            
            app.GRASPMaxItEditField.Value = app.config.GRASP.MaxIt;
            app.GRASPMaxIt2EditField.Value = app.config.GRASP.MaxIt2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            width = 565;
            height = 511;
            
            sz = get(0,'ScreenSize');
            
            x = mean(sz([1,3]));
            y = mean(sz([2,4]));
            app.ParmetrosdelosalgoritmosUIFigure.Position = [x - width/2, y - height/2 , width, height];
            
            Inicializar(app, 'ConfigAlg.mat')
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            root_path = dir('main.m');
            ModelName =strcat(root_path.folder,'\','ConfigAlg.mat');
            Config = app.config;
            
            
            switch app.config_changed
                case true
                    selection = uiconfirm(app.ParmetrosdelosalgoritmosUIFigure,'¿Desea confirmar los cambios?','Confirmación de cambios','Icon','info');
                    if selection == "OK"
                        save(ModelName,'Config');
                        delete(app);
                    end
                otherwise
                    delete(app);
            end
        end

        % Close request function: ParmetrosdelosalgoritmosUIFigure
        function ParmetrosdelosalgoritmosUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: EDAMaxItEditField
        function EDAMaxItEditFieldValueChanged(app, event)
            app.config.EDA.MaxIt = app.EDAMaxItEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: EDAMaxPopEditField
        function EDAMaxPopEditFieldValueChanged(app, event)
            app.config.EDA.MaxPop = app.EDAMaxPopEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: EDAnumParentsEditField
        function EDAnumParentsEditFieldValueChanged(app, event)
            app.config.EDA.numParents = app.EDAnumParentsEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: SAMaxItEditField
        function SAMaxItEditFieldValueChanged(app, event)
            app.config.SA.MaxIt = app.SAMaxItEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: SAMaxIt2EditField
        function SAMaxIt2EditFieldValueChanged(app, event)
            app.config.SA.MaxIt2 = app.SAMaxIt2EditField.Value;
            app.config_changed = true;
        end

        % Value changed function: SAT0EditField
        function SAT0EditFieldValueChanged(app, event)
            app.config.SA.T0 = app.SAT0EditField.Value;
            app.config_changed = true;
        end

        % Value changed function: SAalphaEditField
        function SAalphaEditFieldValueChanged(app, event)
            app.config.SA.alpha = app.SAalphaEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: VNSMaxItEditField
        function VNSMaxItEditFieldValueChanged(app, event)
            app.config.VNS.MaxIt = app.VNSMaxItEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: VNSMaxKEditField
        function VNSMaxKEditFieldValueChanged(app, event)
            app.config.VNS.maxK = app.VNSMaxKEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: GRASPMaxItEditField
        function GRASPMaxItEditFieldValueChanged(app, event)
            app.config.GRASP.MaxIt = app.GRASPMaxItEditField.Value;
            app.config_changed = true;
        end

        % Value changed function: GRASPMaxIt2EditField
        function GRASPMaxIt2EditFieldValueChanged(app, event)
            app.config.GRASP.MaxIt2 = app.GRASPMaxIt2EditField.Value;
            app.config_changed = true;
        end

        % Button pushed function: InicializarpordefectoButton
        function InicializarpordefectoButtonPushed(app, event)
            Inicializar(app, 'DefaultConfigAlg.mat')
            app.config_changed = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParmetrosdelosalgoritmosUIFigure = uifigure('Visible', 'off');
            app.ParmetrosdelosalgoritmosUIFigure.Name = 'Parámetros de los algoritmos';
            app.ParmetrosdelosalgoritmosUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParmetrosdelosalgoritmosUIFigureCloseRequest, true);
            app.EDAPanel = uipanel(app.ParmetrosdelosalgoritmosUIFigure);
            app.EDAPanel.Title = 'EDA';
            app.MaxItEditFieldLabel = uilabel(app.EDAPanel);
            app.MaxItEditFieldLabel.Text = 'MaxIt';
            app.EDAMaxItEditField = uieditfield(app.EDAPanel, 'numeric');
            app.EDAMaxItEditField.ValueChangedFcn = createCallbackFcn(app, @EDAMaxItEditFieldValueChanged, true);
            app.MaxPopEditFieldLabel = uilabel(app.EDAPanel);
            app.MaxPopEditFieldLabel.Text = 'MaxPop';
            app.EDAMaxPopEditField = uieditfield(app.EDAPanel, 'numeric');
            app.EDAMaxPopEditField.ValueChangedFcn = createCallbackFcn(app, @EDAMaxPopEditFieldValueChanged, true);
            app.numParentsEditFieldLabel = uilabel(app.EDAPanel);
            app.numParentsEditFieldLabel.Text = 'numParents';
            app.EDAnumParentsEditField = uieditfield(app.EDAPanel, 'numeric');
            app.EDAnumParentsEditField.ValueChangedFcn = createCallbackFcn(app, @EDAnumParentsEditFieldValueChanged, true);
            app.SAPanel = uipanel(app.ParmetrosdelosalgoritmosUIFigure);
            app.SAPanel.Title = 'SA';
            app.MaxItEditField_2Label = uilabel(app.SAPanel);
            app.MaxItEditField_2Label.Text = 'MaxIt';
            app.SAMaxItEditField = uieditfield(app.SAPanel, 'numeric');
            app.SAMaxItEditField.ValueChangedFcn = createCallbackFcn(app, @SAMaxItEditFieldValueChanged, true);
            app.T0EditFieldLabel = uilabel(app.SAPanel);
            app.T0EditFieldLabel.Text = 'T0';
            app.SAT0EditField = uieditfield(app.SAPanel, 'numeric');
            app.SAT0EditField.ValueChangedFcn = createCallbackFcn(app, @SAT0EditFieldValueChanged, true);
            app.alphaEditFieldLabel = uilabel(app.SAPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.SAalphaEditField = uieditfield(app.SAPanel, 'numeric');
            app.SAalphaEditField.ValueChangedFcn = createCallbackFcn(app, @SAalphaEditFieldValueChanged, true);
            app.MaxIt2EditFieldLabel = uilabel(app.SAPanel);
            app.MaxIt2EditFieldLabel.Text = 'MaxIt2';
            app.SAMaxIt2EditField = uieditfield(app.SAPanel, 'numeric');
            app.SAMaxIt2EditField.ValueChangedFcn = createCallbackFcn(app, @SAMaxIt2EditFieldValueChanged, true);
            app.VNSPanel = uipanel(app.ParmetrosdelosalgoritmosUIFigure);
            app.VNSPanel.Title = 'VNS';
            app.MaxItEditField_3Label = uilabel(app.VNSPanel);
            app.MaxItEditField_3Label.Text = 'MaxIt';
            app.VNSMaxItEditField = uieditfield(app.VNSPanel, 'numeric');
            app.VNSMaxItEditField.ValueChangedFcn = createCallbackFcn(app, @VNSMaxItEditFieldValueChanged, true);
            app.MaxKEditFieldLabel = uilabel(app.VNSPanel);
            app.MaxKEditFieldLabel.Text = 'MaxK';
            app.VNSMaxKEditField = uieditfield(app.VNSPanel, 'numeric');
            app.VNSMaxKEditField.ValueChangedFcn = createCallbackFcn(app, @VNSMaxKEditFieldValueChanged, true);
            app.GRASPPanel = uipanel(app.ParmetrosdelosalgoritmosUIFigure);
            app.GRASPPanel.Title = 'GRASP';
            app.MaxItEditField_4Label = uilabel(app.GRASPPanel);
            app.MaxItEditField_4Label.Text = 'MaxIt';
            app.GRASPMaxItEditField = uieditfield(app.GRASPPanel, 'numeric');
            app.GRASPMaxItEditField.ValueChangedFcn = createCallbackFcn(app, @GRASPMaxItEditFieldValueChanged, true);
            app.MaxIt2EditField_2Label = uilabel(app.GRASPPanel);
            app.MaxIt2EditField_2Label.Text = 'MaxIt2';
            app.GRASPMaxIt2EditField = uieditfield(app.GRASPPanel, 'numeric');
            app.GRASPMaxIt2EditField.ValueChangedFcn = createCallbackFcn(app, @GRASPMaxIt2EditFieldValueChanged, true);
            app.CerrarButton = uibutton(app.ParmetrosdelosalgoritmosUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.InicializarpordefectoButton = uibutton(app.ParmetrosdelosalgoritmosUIFigure, 'push');
            app.InicializarpordefectoButton.ButtonPushedFcn = createCallbackFcn(app, @InicializarpordefectoButtonPushed, true);
            app.InicializarpordefectoButton.Text = 'Inicializar por defecto';
            % Show the figure after all components are created
            app.ParmetrosdelosalgoritmosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appBMConfAlg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParmetrosdelosalgoritmosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParmetrosdelosalgoritmosUIFigure)
        end
    end
end